classdef ProjectSort < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProjectSortUIFigure            matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        SpeedSliderLabel               matlab.ui.control.Label
        SpeedSlider                    matlab.ui.control.Slider
        ProjectSortLabel               matlab.ui.control.Label
        Switch                         matlab.ui.control.ToggleSwitch
        ArrayLengthKnobLabel           matlab.ui.control.Label
        ArrayLengthKnob                matlab.ui.control.DiscreteKnob
        MaxValueLabel                  matlab.ui.control.Label
        MaxValueEditField              matlab.ui.control.NumericEditField
        Button                         matlab.ui.control.Button
        UsePremadeArrayButton          matlab.ui.control.Button
        QuickButton                    matlab.ui.control.Button
        InsertionButton                matlab.ui.control.Button
        MergeButton                    matlab.ui.control.Button
        BubbleButton                   matlab.ui.control.Button
        CountingButton                 matlab.ui.control.Button
        HeapButton                     matlab.ui.control.Button
        VelocityGauge                  matlab.ui.control.SemicircularGauge
        AliEgemenTaren160403034Button  matlab.ui.control.Button
        HaveanyquestionsMailmeButton   matlab.ui.control.Button
        BogoButton                     matlab.ui.control.Button
        CombButton                     matlab.ui.control.Button
        PancakeButton                  matlab.ui.control.Button
        GnomeButton                    matlab.ui.control.Button
        EmbeddedSystemsLabLocationButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        randomArray; % Array itself
        isWorking;   % On/Off
        projectSpeed; %Speed
        arrayLen;     %Array size
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.isWorking = str2double(app.Switch.Value);
            app.projectSpeed = 0.01*(200 - app.SpeedSlider.Value);
            app.arrayLen = str2double(app.ArrayLengthKnob.Value);
            arrayMax = app.MaxValueEditField.Value;
            app.randomArray = randi(arrayMax,1,app.arrayLen);
            bar(app.UIAxes, app.randomArray)
        end

        % Callback function
        function PlotExample(app, event)
            %value = app.SpeedSlider.Value;
            %plot(app.UIAxes, value*peaks)
            %app.UIAxes.YLim = [-1000 1000];
        end

        % Value changed function: ArrayLengthKnob, MaxValueEditField
        function GenerateArray(app, event)
            app.arrayLen = str2double(app.ArrayLengthKnob.Value);
            arrayMax = app.MaxValueEditField.Value;
            app.randomArray = randi(arrayMax,1,app.arrayLen);
            bar(app.UIAxes, app.randomArray)
            drawnow update
        end

        % Value changed function: SpeedSlider, Switch
        function SpeedSettings(app, event)
            app.isWorking = str2double(app.Switch.Value);
            app.projectSpeed = 0.01*(100 - app.SpeedSlider.Value);
            app.VelocityGauge.Value = app.SpeedSlider.Value;
        end

        % Button pushed function: QuickButton
        function QuickSortPushed(app, event)
            pause('on')
            if app.isWorking == 1
                app.randomArray = QuickSort2(app.randomArray,1,length(app.randomArray),app,event);
                bar(app.UIAxes, app.randomArray)
                drawnow update
            end
        end

        % Button pushed function: InsertionButton
        function InsertionSortPushed(app, event)
            pause('on')
            if app.isWorking == 1
                for j = 2:app.arrayLen
                    key_number  =   app.randomArray(j);
                    i           =   j-1           ;
                    while (i>0) && (app.randomArray(i)>key_number) && app.isWorking
                        app.randomArray(i+1)    =   app.randomArray(i)  ;
                        i                   =   i-1             ;
                        pause(app.projectSpeed)
                        bar(app.UIAxes, app.randomArray)
                        drawnow update
                    end
                    app.randomArray(i+1) = key_number;
                end
                bar(app.UIAxes, app.randomArray)
                drawnow update
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            web('http://ikc.edu.tr/', '-browser')
        end

        % Button pushed function: BubbleButton
        function BubbleSortPushed(app, event)
            pause('on')
            if app.isWorking == 1
                for i = 1:(app.arrayLen - 1)
                    for j = app.arrayLen:-1:(i+1)
                        if app.randomArray(j) < app.randomArray((j-1)) && app.isWorking == 1
                            pause(app.projectSpeed)
                            Changekey           =   app.randomArray(j)    ;
                            app.randomArray(j)      =   app.randomArray(j-1)  ;
                            app.randomArray(j-1)    =   Changekey         ;
                            bar(app.UIAxes, app.randomArray)
                            drawnow update
                        end
                    end  
                end
                bar(app.UIAxes, app.randomArray)
                drawnow update
            end
        end

        % Button pushed function: MergeButton
        function MergeSortPushed(app, event)
            if app.isWorking == 1
                app.randomArray = mergeSort(app.randomArray,1,length(app.randomArray),app,event);
            end
        end

        % Button pushed function: CountingButton
        function CountingSortPushed(app, event)
            if app.isWorking == 1
                app.randomArray = CountSort(app, event);
            end
        end

        % Button pushed function: HeapButton
        function HeapSortPushed(app, event)
            if app.isWorking == 1
                app.randomArray = HeapSort(app.randomArray, app, event);
            end
        end

        % Button pushed function: UsePremadeArrayButton
        function UsePremadeArray(app, event)
            [num] = xlsread('customArray.xlsx','B:B');
            app.randomArray = num.';
            app.arrayLen = length(app.randomArray);
            bar(app.UIAxes, app.randomArray)
            drawnow update
        end

        % Button pushed function: AliEgemenTaren160403034Button
        function WebSitePushed(app, event)
            web('https://www.linkedin.com/in/taliegemen/', '-browser')
        end

        % Button pushed function: HaveanyquestionsMailmeButton
        function MailPushed(app, event)
            web mailto:taliegemen@gmail.com
        end

        % Button pushed function: BogoButton
        function BogoPushed(app, event)
            pause('on')
            while ( ~issorted(app.randomArray) ) && app.isWorking %Check to see if it is sorted
                pause(app.projectSpeed)
                app.randomArray = app.randomArray( randperm(numel(app.randomArray)) );
                bar(app.UIAxes, app.randomArray)
                drawnow update
            end
        end

        % Button pushed function: CombButton
        function CombPushed(app, event)
            pause('on')
            
            listSize = numel(app.randomArray);
            gap = int32(listSize); %Coerce gap to an int so we can use the idivide function
            swaps = true; %Swap flag
         
            while not((gap <= 1) && (swaps == false)) && app.isWorking
         
                gap = idivide(gap,1.25,'floor'); %Int divide, floor the resulting operation
         
                if gap < 1
                    gap = 1;
                end
         
                i = 1; %i equals 1 because all arrays are 1 based in MATLAB
                swaps = false;
         
                %i + gap must be subtracted by 1 because the pseudo-code was writen
                %for 0 based arrays
                while not((i + gap - 1) >= listSize) && app.isWorking
         
                    if (app.randomArray(i) > app.randomArray(i+gap))
                        app.randomArray([i i+gap]) = app.randomArray([i+gap i]); %swap
                        pause(app.projectSpeed)
                        bar(app.UIAxes, app.randomArray)
                        drawnow update
                        swaps = true;
                    end
                i = i + 1;
         
                end %while      
            end %while
        end

        % Button pushed function: PancakeButton
        function PancakePushed(app, event)
            pause('on')
            while app.isWorking
                for i = (numel(app.randomArray):-1:2)
             
                    minElem = app.randomArray(i);
                    minIndex = i;
             
                    %Find the min element in the current subset of the list
                    for j = (i:-1:1)    
                        if app.randomArray(j) <= minElem
                            minElem = app.randomArray(j);
                            minIndex = j;
                        end                              
                    end
             
                    %If the element is already in the correct position don't flip
                    if i ~= minIndex
             
                        %First flip flips the min element in the stack to the top
                        app.randomArray(minIndex:-1:1) = app.randomArray(1:minIndex);
                        pause(app.projectSpeed/2)
                        bar(app.UIAxes, app.randomArray)
                        drawnow update
                        %Second flip flips the min element into the correct position in
                        %the stack
                        app.randomArray(i:-1:1) = app.randomArray(1:i);
                        pause(app.projectSpeed/2)
                        bar(app.UIAxes, app.randomArray)
                        drawnow update
             
                    end   
                end %for
                app.randomArray = fliplr(app.randomArray);
                bar(app.UIAxes, app.randomArray)
                drawnow update
                break;
            end
        end

        % Button pushed function: GnomeButton
        function GnomePushed(app, event)
            pause('on')
                i = 2;
                j = 3;
             
                while i <= numel(app.randomArray) && app.isWorking
             
                    if app.randomArray(i-1) <= app.randomArray(i)
                        i = j;
                        j = j+1;
                    else
                        app.randomArray([i-1 i]) = app.randomArray([i i-1]); %Swap
                        pause(app.projectSpeed)
                        bar(app.UIAxes, app.randomArray)
                        drawnow update
                        i = i-1;
                        if i == 1
                            i = j;
                            j = j+1;
                        end
                    end %if
             
                end %while
        end

        % Button pushed function: EmbeddedSystemsLabLocationButton
        function LocationPushed(app, event)
            webmap('Open Street Map')
            mwLat = 38.513534;
            mwLon = 27.032434;
            name = 'Katip Celebi Embedded Systems Lab, Lecturer: Volkan Kilic';
            iconFilename = ('ikculogo.png');
            wmmarker(mwLat, mwLon, 'FeatureName', name, 'Icon', iconFilename);
            wmzoom(16);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProjectSortUIFigure = uifigure;
            app.ProjectSortUIFigure.Name = 'Project Sort';
            app.UIAxes = uiaxes(app.ProjectSortUIFigure);
            title(app.UIAxes, 'Sorting Animation')
            xlabel(app.UIAxes, 'Place')
            ylabel(app.UIAxes, 'Value')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SpeedSliderLabel = uilabel(app.ProjectSortUIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.ProjectSortUIFigure);
            app.SpeedSlider.MajorTicks = [];
            app.SpeedSlider.MajorTickLabels = {'', ''};
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSettings, true);
            app.SpeedSlider.MinorTicks = [];
            app.SpeedSlider.Value = 50;
            app.ProjectSortLabel = uilabel(app.ProjectSortUIFigure);
            app.ProjectSortLabel.Text = 'Project Sort';
            app.Switch = uiswitch(app.ProjectSortUIFigure, 'toggle');
            app.Switch.Items = {'Stop', 'Start'};
            app.Switch.ItemsData = {'0', '1'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SpeedSettings, true);
            app.Switch.Value = '0';
            app.ArrayLengthKnobLabel = uilabel(app.ProjectSortUIFigure);
            app.ArrayLengthKnobLabel.Text = 'Array Length';
            app.ArrayLengthKnob = uiknob(app.ProjectSortUIFigure, 'discrete');
            app.ArrayLengthKnob.Items = {'1', '', '', '', '5', '', '', '', '', '10', '', '', '', '', '15', '', '', '', '', '20', '', '', '', '', '25', '', '', '', '', '30', '', '', '', '', '35', '', '', '', '', '40', '', '', '', '', '45', '', '', '', '', '50'};
            app.ArrayLengthKnob.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50'};
            app.ArrayLengthKnob.ValueChangedFcn = createCallbackFcn(app, @GenerateArray, true);
            app.ArrayLengthKnob.Value = '15';
            app.MaxValueLabel = uilabel(app.ProjectSortUIFigure);
            app.MaxValueLabel.Text = {'Max '; 'Value'};
            app.MaxValueEditField = uieditfield(app.ProjectSortUIFigure, 'numeric');
            app.MaxValueEditField.Limits = [1 1000];
            app.MaxValueEditField.ValueChangedFcn = createCallbackFcn(app, @GenerateArray, true);
            app.MaxValueEditField.Value = 100;
            app.Button = uibutton(app.ProjectSortUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'ikculogo.png';
            app.Button.Text = '';
            app.UsePremadeArrayButton = uibutton(app.ProjectSortUIFigure, 'push');
            app.UsePremadeArrayButton.ButtonPushedFcn = createCallbackFcn(app, @UsePremadeArray, true);
            app.UsePremadeArrayButton.Icon = 'excellogo.png';
            app.UsePremadeArrayButton.Text = 'Use Premade Array';
            app.QuickButton = uibutton(app.ProjectSortUIFigure, 'push');
            app.QuickButton.ButtonPushedFcn = createCallbackFcn(app, @QuickSortPushed, true);
            app.QuickButton.Text = 'Quick';
            app.InsertionButton = uibutton(app.ProjectSortUIFigure, 'push');
            app.InsertionButton.ButtonPushedFcn = createCallbackFcn(app, @InsertionSortPushed, true);
            app.InsertionButton.Text = 'Insertion';
            app.MergeButton = uibutton(app.ProjectSortUIFigure, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeSortPushed, true);
            app.MergeButton.Text = 'Merge';
            app.BubbleButton = uibutton(app.ProjectSortUIFigure, 'push');
            app.BubbleButton.ButtonPushedFcn = createCallbackFcn(app, @BubbleSortPushed, true);
            app.BubbleButton.Text = 'Bubble';
            app.CountingButton = uibutton(app.ProjectSortUIFigure, 'push');
            app.CountingButton.ButtonPushedFcn = createCallbackFcn(app, @CountingSortPushed, true);
            app.CountingButton.Text = 'Counting';
            app.HeapButton = uibutton(app.ProjectSortUIFigure, 'push');
            app.HeapButton.ButtonPushedFcn = createCallbackFcn(app, @HeapSortPushed, true);
            app.HeapButton.Text = 'Heap';
            app.VelocityGauge = uigauge(app.ProjectSortUIFigure, 'semicircular');
            app.VelocityGauge.MajorTickLabels = {'0', '20', '40', '60', '80', 'Max'};
            app.VelocityGauge.Value = 50;
            app.AliEgemenTaren160403034Button = uibutton(app.ProjectSortUIFigure, 'push');
            app.AliEgemenTaren160403034Button.ButtonPushedFcn = createCallbackFcn(app, @WebSitePushed, true);
            app.AliEgemenTaren160403034Button.Text = {'Ali Egemen Taşören'; '160403034'};
            app.HaveanyquestionsMailmeButton = uibutton(app.ProjectSortUIFigure, 'push');
            app.HaveanyquestionsMailmeButton.ButtonPushedFcn = createCallbackFcn(app, @MailPushed, true);
            app.HaveanyquestionsMailmeButton.Icon = 'maillogo.png';
            app.HaveanyquestionsMailmeButton.Text = {'Have any questions?'; 'Mail me.'};
            app.BogoButton = uibutton(app.ProjectSortUIFigure, 'push');
            app.BogoButton.ButtonPushedFcn = createCallbackFcn(app, @BogoPushed, true);
            app.BogoButton.Text = 'Bogo';
            app.CombButton = uibutton(app.ProjectSortUIFigure, 'push');
            app.CombButton.ButtonPushedFcn = createCallbackFcn(app, @CombPushed, true);
            app.CombButton.Text = 'Comb';
            app.PancakeButton = uibutton(app.ProjectSortUIFigure, 'push');
            app.PancakeButton.ButtonPushedFcn = createCallbackFcn(app, @PancakePushed, true);
            app.PancakeButton.Text = 'Pancake';
            app.GnomeButton = uibutton(app.ProjectSortUIFigure, 'push');
            app.GnomeButton.ButtonPushedFcn = createCallbackFcn(app, @GnomePushed, true);
            app.GnomeButton.Text = 'Gnome';
            app.EmbeddedSystemsLabLocationButton = uibutton(app.ProjectSortUIFigure, 'push');
            app.EmbeddedSystemsLabLocationButton.ButtonPushedFcn = createCallbackFcn(app, @LocationPushed, true);
            app.EmbeddedSystemsLabLocationButton.Text = {'Embedded Systems'; 'Lab Location'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProjectSort

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProjectSortUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProjectSortUIFigure)
        end
    end
end